classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        CalibrationTab             matlab.ui.container.Tab
        y4EditField                matlab.ui.control.NumericEditField
        y4EditFieldLabel           matlab.ui.control.Label
        x4EditField                matlab.ui.control.NumericEditField
        x4EditFieldLabel           matlab.ui.control.Label
        y3EditField                matlab.ui.control.NumericEditField
        y3EditFieldLabel           matlab.ui.control.Label
        x3EditField                matlab.ui.control.NumericEditField
        x3EditFieldLabel           matlab.ui.control.Label
        y2EditField                matlab.ui.control.NumericEditField
        y2EditFieldLabel           matlab.ui.control.Label
        x2EditField                matlab.ui.control.NumericEditField
        x2EditFieldLabel           matlab.ui.control.Label
        y1EditField                matlab.ui.control.NumericEditField
        y1EditFieldLabel           matlab.ui.control.Label
        x1EditField                matlab.ui.control.NumericEditField
        x1EditFieldLabel           matlab.ui.control.Label
        v4EditField                matlab.ui.control.NumericEditField
        v4EditFieldLabel           matlab.ui.control.Label
        u4EditField                matlab.ui.control.NumericEditField
        u4EditFieldLabel           matlab.ui.control.Label
        v3EditField                matlab.ui.control.NumericEditField
        v3EditFieldLabel           matlab.ui.control.Label
        u3EditField                matlab.ui.control.NumericEditField
        u3EditFieldLabel           matlab.ui.control.Label
        v2EditField                matlab.ui.control.NumericEditField
        v2EditFieldLabel           matlab.ui.control.Label
        u2EditField                matlab.ui.control.NumericEditField
        u2EditFieldLabel           matlab.ui.control.Label
        v1EditField                matlab.ui.control.NumericEditField
        v1EditFieldLabel           matlab.ui.control.Label
        u1EditField                matlab.ui.control.NumericEditField
        u1EditFieldLabel           matlab.ui.control.Label
        CalculateHomographyButton  matlab.ui.control.Button
        OpenImageButton            matlab.ui.control.Button
        Tab2                       matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenImageButton
        function OpenImageButtonPushed(app, event)
            % open and read image
            [fname, fpath] = uigetfile({'*.*'}, 'open image');
            image = imread([fpath fname]);
            imshow(image);
            % select 1 points
            uiwait(msgbox('Select 4 points on figure and press "enter"'));
            [u,v] = getpts;
            
            app.u1EditField.Value = u(1,1);
            app.v1EditField.Value = v(1,1);

            app.u2EditField.Value = u(2,1);
            app.v2EditField.Value = v(2,1);
            
            app.u3EditField.Value = u(3,1);
            app.v3EditField.Value = v(3,1);

            app.u4EditField.Value = u(4,1);
            app.v4EditField.Value = v(4,1);
        end

        % Button pushed function: CalculateHomographyButton
        function CalculateHomographyButtonPushed(app, event)
            % get the 4 points value from previous selection
            u1 = app.u1EditField.Value;
            v1 = app.v1EditField.Value;
            u2 = app.u2EditField.Value;
            v2 = app.v2EditField.Value;
            u3 = app.u3EditField.Value;
            v3 = app.v3EditField.Value;
            u4 = app.u4EditField.Value;
            v4 = app.v4EditField.Value;
            
            x1 = app.x1EditField.Value;
            y1 = app.y1EditField.Value;
            x2 = app.x2EditField.Value;
            y2 = app.y2EditField.Value;
            x3 = app.x3EditField.Value;
            y3 = app.y3EditField.Value;
            x4 = app.x4EditField.Value;
            y4 = app.y4EditField.Value;
            
            % calculate homography matrix
            sympref('FloatingPointOutput',true);
            syms a b c d e f g h;
            eqns = [x1 == (u1*a + v1*b + c)/(u1*g + v1*h + 1),...
                    y1 == (u1*d + v1*e + f)/(u1*g + v1*h + 1),...
                    x2 == (u2*a + v2*b + c)/(u2*g + v2*h + 1),...
                    y2 == (u2*d + v2*e + f)/(u2*g + v2*h + 1),...
                    x3 == (u3*a + v3*b + c)/(u3*g + v3*h + 1),...
                    y3 == (u3*d + v3*e + f)/(u3*g + v3*h + 1),...
                    x4 == (u4*a + v4*b + c)/(u4*g + v4*h + 1),...
                    y4 == (u4*d + v4*e + f)/(u4*g + v4*h + 1)];

            param = solve(eqns, [a b c d e f g h]);

            % convert to transformation matrix
            tf_matrix = [double(param.a), double(param.b), double(param.c);
                         double(param.d), double(param.e), double(param.f);
                         double(param.g), double(param.h), 1];
            disp(class(tf_matrix));
            var.homo = tf_matrix;
            save('homography_matrix.mat', 'var')
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.OpenImageButton = uibutton(app.CalibrationTab, 'push');
            app.OpenImageButton.ButtonPushedFcn = createCallbackFcn(app, @OpenImageButtonPushed, true);
            app.OpenImageButton.Text = 'Open Image';
            app.CalculateHomographyButton = uibutton(app.CalibrationTab, 'push');
            app.CalculateHomographyButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateHomographyButtonPushed, true);
            app.CalculateHomographyButton.Text = 'Calculate Homography';
            app.u1EditFieldLabel = uilabel(app.CalibrationTab);
            app.u1EditFieldLabel.Text = 'u1';
            app.u1EditField = uieditfield(app.CalibrationTab, 'numeric');
            app.v1EditFieldLabel = uilabel(app.CalibrationTab);
            app.v1EditFieldLabel.Text = 'v1';
            app.v1EditField = uieditfield(app.CalibrationTab, 'numeric');
            app.u2EditFieldLabel = uilabel(app.CalibrationTab);
            app.u2EditFieldLabel.Text = 'u2';
            app.u2EditField = uieditfield(app.CalibrationTab, 'numeric');
            app.v2EditFieldLabel = uilabel(app.CalibrationTab);
            app.v2EditFieldLabel.Text = 'v2';
            app.v2EditField = uieditfield(app.CalibrationTab, 'numeric');
            app.u3EditFieldLabel = uilabel(app.CalibrationTab);
            app.u3EditFieldLabel.Text = 'u3';
            app.u3EditField = uieditfield(app.CalibrationTab, 'numeric');
            app.v3EditFieldLabel = uilabel(app.CalibrationTab);
            app.v3EditFieldLabel.Text = 'v3';
            app.v3EditField = uieditfield(app.CalibrationTab, 'numeric');
            app.u4EditFieldLabel = uilabel(app.CalibrationTab);
            app.u4EditFieldLabel.Text = 'u4';
            app.u4EditField = uieditfield(app.CalibrationTab, 'numeric');
            app.v4EditFieldLabel = uilabel(app.CalibrationTab);
            app.v4EditFieldLabel.Text = 'v4';
            app.v4EditField = uieditfield(app.CalibrationTab, 'numeric');
            app.x1EditFieldLabel = uilabel(app.CalibrationTab);
            app.x1EditFieldLabel.Text = 'x1';
            app.x1EditField = uieditfield(app.CalibrationTab, 'numeric');
            app.y1EditFieldLabel = uilabel(app.CalibrationTab);
            app.y1EditFieldLabel.Text = 'y1';
            app.y1EditField = uieditfield(app.CalibrationTab, 'numeric');
            app.x2EditFieldLabel = uilabel(app.CalibrationTab);
            app.x2EditFieldLabel.Text = 'x2';
            app.x2EditField = uieditfield(app.CalibrationTab, 'numeric');
            app.y2EditFieldLabel = uilabel(app.CalibrationTab);
            app.y2EditFieldLabel.Text = 'y2';
            app.y2EditField = uieditfield(app.CalibrationTab, 'numeric');
            app.x3EditFieldLabel = uilabel(app.CalibrationTab);
            app.x3EditFieldLabel.Text = 'x3';
            app.x3EditField = uieditfield(app.CalibrationTab, 'numeric');
            app.y3EditFieldLabel = uilabel(app.CalibrationTab);
            app.y3EditFieldLabel.Text = 'y3';
            app.y3EditField = uieditfield(app.CalibrationTab, 'numeric');
            app.x4EditFieldLabel = uilabel(app.CalibrationTab);
            app.x4EditFieldLabel.Text = 'x4';
            app.x4EditField = uieditfield(app.CalibrationTab, 'numeric');
            app.y4EditFieldLabel = uilabel(app.CalibrationTab);
            app.y4EditFieldLabel.Text = 'y4';
            app.y4EditField = uieditfield(app.CalibrationTab, 'numeric');
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end